classdef targeting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SnaptoPointCheckBox   matlab.ui.control.CheckBox
        point1                matlab.ui.control.Label
        SetOriginButton       matlab.ui.control.Button
        LengthEditField       matlab.ui.control.NumericEditField
        LengthEditFieldLabel  matlab.ui.control.Label
        AngleEditField        matlab.ui.control.NumericEditField
        AngleEditFieldLabel   matlab.ui.control.Label
        ManualEditingLabel    matlab.ui.control.Label
        SetHScaleButton       matlab.ui.control.Button
        LoadButton            matlab.ui.control.Button
        LineButton            matlab.ui.control.Button
        SetVScaleButton       matlab.ui.control.Button
        ImageAxes             matlab.ui.control.UIAxes
    end


    properties (Access = public)
        line_prop % Description
        t         % text
        vscale
        hscale
        currentROI
        origin
    end


    methods (Access = private)

        function linemoved(app,~,event)
            handles = guidata(app.UIFigure);

            evname = event.EventName;
            app.currentROI = event.Source.UserData;


            prevlength = pdist([event.PreviousPosition(1,:);event.PreviousPosition(2,:)],'euclidean');
            currlength = pdist([event.CurrentPosition(1,:);event.CurrentPosition(2,:)],'euclidean');
            lengthchange = currlength~=prevlength;
            if app.SnaptoPointCheckBox.Value ==1
                for j = 1:length(app.line_prop) %need to skip self line
                    for i = 1:2
                        for ii = 1:2
%                             if event.Source.UserData ~= app.line_prop{j}.UserData && ...
%                                     pdist([event.CurrentPosition(i,:);app.line_prop{j}.Position(ii,:)],'euclidean')<30
%                                 if lengthchange
%                                     app.line_prop{app.currentROI}.Position(i,:) = app.line_prop{j}.Position(ii,:);
%                                 else
%                                     xdist = app.line_prop{j}.Position(ii,1) - event.CurrentPosition(i,1);
%                                     ydist = app.line_prop{j}.Position(ii,2) - event.CurrentPosition(i,2);
%                                     app.line_prop{app.currentROI}.Position(:,1) = event.CurrentPosition(:,1) + xdist;
%                                     app.line_prop{app.currentROI}.Position(:,2) = event.CurrentPosition(:,2) + ydist;
%                                 end
%                                 
%                                 break
%                             end
                            if event.Source.UserData ~= app.line_prop{j}.UserData && ...
                                    pdist([event.CurrentPosition(i,:);app.line_prop{j}.Position(ii,1) event.CurrentPosition(i,2)],'euclidean')<30
                                if lengthchange
                                    app.line_prop{app.currentROI}.Position(i,1) = app.line_prop{j}.Position(ii,1);
                                else
                                    xdist = app.line_prop{j}.Position(ii,1) - event.CurrentPosition(i,1);
%                                     ydist = app.line_prop{j}.Position(ii,2) - event.CurrentPosition(i,2);
                                    app.line_prop{app.currentROI}.Position(:,1) = event.CurrentPosition(:,1) + xdist;
%                                     app.line_prop{app.currentROI}.Position(:,2) = event.CurrentPosition(:,2) + ydist;
                                end
                                
                                
                            end
                            if event.Source.UserData ~= app.line_prop{j}.UserData && ...
                                    pdist([event.CurrentPosition(i,:);event.CurrentPosition(i,1) app.line_prop{j}.Position(ii,2)],'euclidean')<30
                                if lengthchange
                                    app.line_prop{app.currentROI}.Position(i,2) = app.line_prop{j}.Position(ii,2);
                                else
%                                     xdist = app.line_prop{j}.Position(ii,1) - event.CurrentPosition(i,1);
                                    ydist = app.line_prop{j}.Position(ii,2) - event.CurrentPosition(i,2);
%                                     app.line_prop{app.currentROI}.Position(:,1) = event.CurrentPosition(:,1) + xdist;
                                    app.line_prop{app.currentROI}.Position(:,2) = event.CurrentPosition(:,2) + ydist;
                                end
                                
                                
                            end
                        end

                    end
                end
            end

            lineNum = event.Source.UserData;
            app.line_prop{lineNum}.Position = event.Source.Position;

            coord = app.line_prop{lineNum}.Position;
            xlength = coord(2,1) - coord(1,1);
            ylength = coord(2,2) - coord(1,2);
            xscaled = xlength/handles.hlength;
            yscaled = ylength/handles.vlength;
            linelength = sqrt(xscaled^2 + yscaled^2);
            angle = atand(xscaled/yscaled);

            app.line_prop{lineNum}.Label = sprintf('%.3f mm @ %.3f%c',linelength,angle,char(176));
            if ~isempty(app.origin)
                if app.SnaptoPointCheckBox.Value == 1
                    for i = 1:2
    %                     if pdist([event.CurrentPosition(i,:);app.origin.Position],'euclidean')<30
    %                         if lengthchange
    %                             app.line_prop{app.currentROI}.Position(i,:) = app.origin.Position;
    %                         else
    %                             xdist = app.origin.Position(1) - event.CurrentPosition(i,1);
    %                             ydist = app.origin.Position(2) - event.CurrentPosition(i,2);
    %                             app.line_prop{app.currentROI}.Position(:,1) = event.CurrentPosition(:,1) + xdist;
    %                             app.line_prop{app.currentROI}.Position(:,2) = event.CurrentPosition(:,2) + ydist;
    %                         end
    %                     end

                        if pdist([event.CurrentPosition(i,:);app.origin.Position(1) event.CurrentPosition(i,2)],'euclidean')<30
                            if lengthchange
                                app.line_prop{app.currentROI}.Position(i,1) = app.origin.Position(1);
                            else
                                xdist = app.origin.Position(1) - event.CurrentPosition(i,1);
                                %                             ydist = app.origin.Position(2) - event.CurrentPosition(i,2);
                                app.line_prop{app.currentROI}.Position(:,1) = event.CurrentPosition(:,1) + xdist;
                                %                             app.line_prop{app.currentROI}.Position(:,2) = event.CurrentPosition(:,2) + ydist;
                            end
                        end
                        if pdist([event.CurrentPosition(i,:);event.CurrentPosition(i,1) app.origin.Position(2)],'euclidean')<30
                            if lengthchange
                                app.line_prop{app.currentROI}.Position(i,2) = app.origin.Position(2);
                            else
                                %                             xdist = app.origin.Position(1) - event.CurrentPosition(i,1);
                                ydist = app.origin.Position(2) - event.CurrentPosition(i,2);
                                %                             app.line_prop{app.currentROI}.Position(:,1) = event.CurrentPosition(:,1) + xdist;
                                app.line_prop{app.currentROI}.Position(:,2) = event.CurrentPosition(:,2) + ydist;
                            end
                        end
                    end
                end
                xpoint1 = (event.Source.Position(1,1) - app.origin.Position(1,1))/handles.hlength;
                ypoint1 = (event.Source.Position(1,2) - app.origin.Position(1,2))/handles.vlength;
                xpoint2 = (event.Source.Position(2,1) - app.origin.Position(1,1))/handles.hlength;
                ypoint2 = (event.Source.Position(2,2) - app.origin.Position(1,2))/handles.vlength;

                app.point1.Text = sprintf('Point 1: X(%.3f) Y(%.3f) \t Point 2: X(%.3f) Y(%.3f)',...
                    xpoint1,ypoint1,xpoint2,ypoint2);
                app.AngleEditField.Value = angle;
                app.LengthEditField.Value = linelength;
                app.currentROI = event.Source.UserData;


            end


        end

        function linemoving(app,~,event)
            handles = guidata(app.UIFigure);


            evname = event.EventName;
            lineNum = event.Source.UserData;
            app.line_prop{lineNum}.Position = event.Source.Position;

            coord = app.line_prop{lineNum}.Position;
            xlength = coord(2,1) - coord(1,1);
            ylength = coord(2,2) - coord(1,2);
            xscaled = xlength/handles.hlength;
            yscaled = ylength/handles.vlength;
            linelength = sqrt(xscaled^2 + yscaled^2);
            angle = atand(xscaled/yscaled);

            %             app.t{lineNum}.String = sprintf('Length = %.3f mm',linelength);
            app.line_prop{lineNum}.Label = sprintf('%.3f mm @ %.3f%c',linelength,angle,char(176));

            %             if abs(yscaled/xscaled) <0.5
            %                 app.t{lineNum}.Position = [coord(1,1)+0.5*xlength,coord(1,2)+0.5*ylength+0.2*handles.vlength 0];
            %                 app.t{lineNum}.HorizontalAlignment = 'center';
            %             else
            %                 app.t{lineNum}.Position = [coord(1,1)+0.5*xlength,coord(1,2)+0.5*ylength];
            %                 app.t{lineNum}.HorizontalAlignment = 'left';
            %
            %             end
            if ~isempty(app.origin)
                xpoint1 = (event.Source.Position(1,1) - app.origin.Position(1,1))/handles.hlength;
                ypoint1 = (event.Source.Position(1,2) - app.origin.Position(1,2))/handles.vlength;
                xpoint2 = (event.Source.Position(2,1) - app.origin.Position(1,1))/handles.hlength;
                ypoint2 = (event.Source.Position(2,2) - app.origin.Position(1,2))/handles.vlength;

                app.point1.Text = sprintf('Point 1: X(%.3f) Y(%.3f) \t Point 2: X(%.3f) Y(%.3f)',...
                    xpoint1,ypoint1,xpoint2,ypoint2);
                app.AngleEditField.Value = angle;
                app.LengthEditField.Value = linelength;
                app.currentROI = event.Source.UserData;

            end

        end

        function scalemoved(app,~,event)
            handles = guidata(app.UIFigure);

            if strcmp(event.Source.Label,'Vertical Scale')
                coord = event.Source.Position;
                vlength = coord(2,2)-coord(1,2);
                handles.vlength = vlength;
            elseif strcmnp(event.Source.Label,'Horizontal Scale')
                coord = event.Source.Position;
                hlength = coord(2,1)-coord(1,1);
                handles.hlength = hlength;
            end
            for j = 1:length(app.line_prop)
                coord = app.line_prop{j}.Position;
                xlength = coord(2,1) - coord(1,1);
                ylength = coord(2,2) - coord(1,2);
                xscaled = xlength/handles.hlength;
                yscaled = ylength/handles.vlength;
                linelength = sqrt(xscaled^2 + yscaled^2);
                angle = atand(xscaled/yscaled);
                app.line_prop{j}.Label = sprintf('%.3f mm @ %.3f%c',linelength,angle,char(176));


            end
            guidata(app.UIFigure,handles)
        end


        function scalemoving(app,~,event)
            handles = guidata(app.UIFigure);

            if strcmp(event.Source.Label,'Vertical Scale')
                coord = event.Source.Position;
                vlength = coord(2,2)-coord(1,2);
                handles.vlength = vlength;
            elseif strcmp(event.Source.Label,'Horizontal Scale')
                coord = event.Source.Position;
                hlength = coord(2,1)-coord(1,1);
                handles.hlength = hlength;
            end
            for j = 1:length(app.line_prop)
                coord = app.line_prop{j}.Position;
                xlength = coord(2,1) - coord(1,1);
                ylength = coord(2,2) - coord(1,2);
                xscaled = xlength/handles.hlength;
                yscaled = ylength/handles.vlength;
                linelength = sqrt(xscaled^2 + yscaled^2);
                angle = atand(xscaled/yscaled);
                app.line_prop{j}.Label = sprintf('%.3f mm @ %.3f%c',linelength,angle,char(176));


            end
            guidata(app.UIFigure,handles)

        end

        function Clicked(app,~,event)
            handles = guidata(app.UIFigure);
            app.currentROI = event.Source.UserData;

            coord = app.line_prop{app.currentROI}.Position;
            xlength = coord(2,1) - coord(1,1);
            ylength = coord(2,2) - coord(1,2);
            xscaled = xlength/handles.hlength;
            yscaled = ylength/handles.vlength;
            angle = atand(xscaled/yscaled);
            linelengthscaled = sqrt(xscaled^2 + yscaled^2);
            app.AngleEditField.Value = angle;
            app.LengthEditField.Value = linelengthscaled;
            if ~isempty(app.origin)
                xpoint1 = (event.Source.Position(1,1) - app.origin.Position(1,1))/handles.hlength;
                ypoint1 = (event.Source.Position(1,2) - app.origin.Position(1,2))/handles.vlength;
                xpoint2 = (event.Source.Position(2,1) - app.origin.Position(1,1))/handles.hlength;
                ypoint2 = (event.Source.Position(2,2) - app.origin.Position(1,2))/handles.vlength;

                app.point1.Text = sprintf('Point 1: X(%.3f) Y(%.3f) \t Point 2: X(%.3f) Y(%.3f)',...
                    xpoint1,ypoint1,xpoint2,ypoint2);

            end

            guidata(app.UIFigure,handles)
        end


        function originmoved(app,~,event)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            cla(app.ImageAxes)
            counter = 1;
            handles.counter =1;
            [file,path] = uigetfile('*.*');
            image = imread([path file]);
            %             set(app.ImageAxes,'Units','pixels');
            %             imsize = get(app.ImageAxes,'Position');
            %             image = imresize(image,[])
            imshow(image,'Parent',app.ImageAxes)
            handles.image = image;
            guidata(app.UIFigure,handles)
        end

        % Button pushed function: SetVScaleButton
        function SetVScaleButtonPushed(app, event)
            handles = guidata(app.UIFigure);
            line_prop = drawline(app.ImageAxes);
            app.vscale = line_prop;
            app.vscale.Label = 'Vertical Scale';
            coord = line_prop.Position;
            vlength = coord(2,2)-coord(1,2);
            addlistener(app.vscale,'ROIMoved',@app.scalemoved);
            addlistener(app.vscale,'MovingROI',@app.scalemoving);
            handles.vlength = vlength;
            guidata(app.UIFigure,handles)
        end

        % Button pushed function: SetHScaleButton
        function SetHScaleButtonPushed(app, event)
            handles = guidata(app.UIFigure);
            line_prop = drawline(app.ImageAxes);
            app.hscale = line_prop;
            app.hscale.Label = 'Horizontal Scale';
            coord = line_prop.Position;
            hlength = coord(2,1)-coord(1,1);
            addlistener(app.hscale,'ROIMoved',@app.scalemoved);
            addlistener(app.hscale,'MovingROI',@app.scalemoving);
            handles.hlength = hlength;
            guidata(app.UIFigure,handles)
        end

        % Button pushed function: LineButton
        function LineButtonPushed(app, event)
            handles = guidata(app.UIFigure);
            counter = handles.counter;
            line_prop{counter} = drawline(app.ImageAxes,'Color','red');
            app.line_prop{counter} = line_prop{counter};
            app.currentROI = counter;
            app.line_prop{counter}.UserData = counter;
            coord{counter} = line_prop{counter}.Position;
            xlength = coord{counter}(2,1) - coord{counter}(1,1);
            ylength = coord{counter}(2,2) - coord{counter}(1,2);
            xscaled = xlength/handles.hlength;
            yscaled = ylength/handles.vlength;
            linelength = sqrt(xscaled^2 + yscaled^2);
            angle = atand(xscaled/yscaled);
            app.line_prop{counter}.Label = sprintf('%.3f mm @ %.3f%c',linelength,angle,char(176));
            %             if abs(yscaled/xscaled) <0.5
            %                 app.t{counter} = text(app.ImageAxes,coord{counter}(1,1)+0.5*xlength,coord{counter}(1,2)+0.5*ylength+0.2*handles.vlength,...
            %                     sprintf('Length = %.3f mm',linelength),'FontSize',16,'HorizontalAlignment',"center",...
            %                     'Color','red');
            %             else
            %                 app.t{counter} = text(app.ImageAxes,coord{counter}(1,1)+0.5*xlength,coord{counter}(1,2)+0.5*ylength,...
            %                     sprintf('Length = %.3f mm',linelength),'FontSize',16,'Color','red',"HorizontalAlignment",'left');
            %             end
            addlistener(app.line_prop{counter},'ROIMoved',@app.linemoved);
            addlistener(app.line_prop{counter},'MovingROI',@app.linemoving);
            addlistener(app.line_prop{counter},'ROIClicked',@app.Clicked);


            %             addlistener(line_prop(counter),'ROIMoved',@allevents);
            app.AngleEditField.Value = angle;
            app.LengthEditField.Value = linelength;
            counter = counter +1;
            handles.counter = counter;
            guidata(app.UIFigure,handles)

        end

        % Callback function
        function TextEditButtonValueChanged(app, event)
            value = app.TextEditButton.Value;
            if value ==1
                plotedit(app.ImageAxes,'on')
            else
                plotedit(app.ImageAxes,'off')
            end
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            newangle = app.AngleEditField.Value;
            handles = guidata(app.UIFigure);

            lineOI = app.currentROI;
            coord = app.line_prop{lineOI}.Position;
            xlength = coord(2,1) - coord(1,1);
            ylength = coord(2,2) - coord(1,2);
            xscaled = xlength/handles.hlength;
            yscaled = ylength/handles.vlength;
            %             linelength = sqrt(xlength^2 + ylength^2);
            linelengthscaled = sqrt(xscaled^2 + yscaled^2);
            center = [0.5*xlength+coord(1,1) 0.5*ylength+coord(1,2)];
            app.line_prop{lineOI}.Position(2,:) = [center(1)+0.5*handles.hlength*linelengthscaled*sind(newangle)...
                center(2)+0.5*handles.vlength*linelengthscaled*cosd(newangle)];
            app.line_prop{lineOI}.Position(1,:) = [center(1)-0.5*handles.hlength*linelengthscaled*sind(newangle) ...
                center(2)-0.5*handles.vlength*linelengthscaled*cosd(newangle)];

            coord = app.line_prop{lineOI}.Position;
            xlength = coord(2,1) - coord(1,1);
            ylength = coord(2,2) - coord(1,2);
            xscaled = xlength/handles.hlength;
            yscaled = ylength/handles.vlength;
            linelengthscaled = sqrt(xscaled^2 + yscaled^2);
            app.line_prop{lineOI}.Label = sprintf('%.3f mm @ %.3f%c',linelengthscaled,newangle,char(176));

            guidata(app.UIFigure,handles)

        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            newlength = app.LengthEditField.Value;
            handles = guidata(app.UIFigure);
            lineOI = app.currentROI;
            coord = app.line_prop{lineOI}.Position;
            xlength = coord(2,1) - coord(1,1);
            ylength = coord(2,2) - coord(1,2);
            xscaled = xlength/handles.hlength;
            yscaled = ylength/handles.vlength;
            angle = atand(xscaled/yscaled);
            center = [0.5*xlength+coord(1,1) 0.5*ylength+coord(1,2)];

            app.line_prop{lineOI}.Position(2,:) = [center(1)+0.5*newlength*sind(angle)*handles.hlength ...
                center(2)+0.5*newlength*cosd(angle)*handles.vlength];
            app.line_prop{lineOI}.Position(1,:) = [center(1)-0.5*newlength*sind(angle)*handles.hlength ...
                center(2)-0.5*newlength*cosd(angle)*handles.vlength];
            coord = app.line_prop{lineOI}.Position;
            xlength = coord(2,1) - coord(1,1);
            ylength = coord(2,2) - coord(1,2);
            xscaled = xlength/handles.hlength;
            yscaled = ylength/handles.vlength;
            linelengthscaled = sqrt(xscaled^2 + yscaled^2);

            app.line_prop{lineOI}.Label = sprintf('%.3f mm @ %.3f%c',linelengthscaled,angle,char(176));

            guidata(app.UIFigure,handles)

        end

        % Button pushed function: SetOriginButton
        function SetOriginButtonPushed(app, event)

            app.origin = drawpoint(app.ImageAxes);
            addlistener(app.origin,'ROIMoved',@app.originmoved);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            handles = guidata(app.UIFigure);
            if ~isempty(app.line_prop)
                switch key
                    case 'rightarrow'
                        app.line_prop{app.currentROI}.Position(:,1) = app.line_prop{app.currentROI}.Position(:,1) +0.5;
                    case 'leftarrow'
                        app.line_prop{app.currentROI}.Position(:,1) = app.line_prop{app.currentROI}.Position(:,1) -0.5;
                    case 'uparrow'
                        app.line_prop{app.currentROI}.Position(:,2) = app.line_prop{app.currentROI}.Position(:,2) -0.5;
                    case 'downarrow'
                        app.line_prop{app.currentROI}.Position(:,2) = app.line_prop{app.currentROI}.Position(:,2) +0.5;
                end
                if ~isempty(app.origin)
                    xpoint1 = (app.line_prop{app.currentROI}.Position(1,1) - app.origin.Position(1,1))/handles.hlength;
                    ypoint1 = (app.line_prop{app.currentROI}.Position(1,2) - app.origin.Position(1,2))/handles.vlength;
                    xpoint2 = (app.line_prop{app.currentROI}.Position(2,1) - app.origin.Position(1,1))/handles.hlength;
                    ypoint2 = (app.line_prop{app.currentROI}.Position(2,2) - app.origin.Position(1,2))/handles.vlength;

                    app.point1.Text = sprintf('Point 1: X(%.3f) Y(%.3f) \t Point 2: X(%.3f) Y(%.3f)',...
                        xpoint1,ypoint1,xpoint2,ypoint2);

                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.SetVScaleButton = uibutton(app.UIFigure, 'push');
            app.SetVScaleButton.ButtonPushedFcn = createCallbackFcn(app, @SetVScaleButtonPushed, true);
            app.SetVScaleButton.Text = 'Set V-Scale';
            app.LineButton = uibutton(app.UIFigure, 'push');
            app.LineButton.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.LineButton.Text = {'Line'; ''};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SetHScaleButton = uibutton(app.UIFigure, 'push');
            app.SetHScaleButton.ButtonPushedFcn = createCallbackFcn(app, @SetHScaleButtonPushed, true);
            app.SetHScaleButton.Text = 'Set H-Scale';
            app.ManualEditingLabel = uilabel(app.UIFigure);
            app.ManualEditingLabel.Text = 'Manual Editing:';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.SetOriginButton = uibutton(app.UIFigure, 'push');
            app.SetOriginButton.ButtonPushedFcn = createCallbackFcn(app, @SetOriginButtonPushed, true);
            app.SetOriginButton.Text = 'Set Origin';
            app.point1 = uilabel(app.UIFigure);
            app.point1.Text = '';
            app.SnaptoPointCheckBox = uicheckbox(app.UIFigure);
            app.SnaptoPointCheckBox.Text = 'Snap to Point';
            app.SnaptoPointCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = targeting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end